classdef SimTask_MeshGraphBasedStructDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MeshGraphbasedStructuralDesignPanel  matlab.ui.container.Panel
        PreprocessPanel                 matlab.ui.container.Panel
        ExternalGraphCheckBox           matlab.ui.control.CheckBox
        DataAlignmentButton             matlab.ui.control.Button
        EdgeVoxelizationPanel           matlab.ui.container.Panel
        VoxelizeMeshGraphEdgesButton    matlab.ui.control.Button
        EvaluationPanel                 matlab.ui.container.Panel
        EvaluateStressAlignmentScaleButton  matlab.ui.control.Button
        StiffnessEvaluateofVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        ConvertPSLstoMaterialLayoutPanel  matlab.ui.container.Panel
        CreateVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        FixedAreaEditField_4            matlab.ui.control.NumericEditField
        FixedAreaEditField_4Label       matlab.ui.control.Label
        LoadingAreaEditField_4          matlab.ui.control.NumericEditField
        LoadingAreaEditField_4Label     matlab.ui.control.Label
        EntireBoundaryEditField         matlab.ui.control.NumericEditField
        EntireBoundaryEditFieldLabel    matlab.ui.control.Label
        EdgeThicknessEditField          matlab.ui.control.NumericEditField
        EdgeThicknessEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global vertexEdgeGraph_;
            global meshHierarchy_;
            app.MainApp = mainapp;
            GatherLSSandMPsettings(app.MainApp);
            app.ExternalGraphCheckBox.Enable = "off";
            app.VoxelizeMeshGraphEdgesButton.Enable = 'off';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'off';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off';
            app.EdgeThicknessEditField.Enable = "off";
            app.LoadingAreaEditField_4.Enable = 'off';
            app.FixedAreaEditField_4.Enable = 'off';
            app.EntireBoundaryEditField.Enable = 'off';
            % app.DesignComplianceEditField.Enable = 'off';
            % app.DesignVolumeFractionEditField.Enable = 'off';
            app.DesignComplianceEditField.Editable = 'off';
            app.DesignVolumeFractionEditField.Editable = 'off';            
            if 0==meshHierarchy_(1).state
                warning('No volume is available for subsequent tasks!');
                app.DataAlignmentButton.Enable = 'off';
            end
            if 0==vertexEdgeGraph_.state
                app.ExternalGraphCheckBox.Value = 0;                
            else
                app.ExternalGraphCheckBox.Value = 1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';
                app.MainApp.EvaluationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';
                app.MainApp.EvaluationTasksDropDown.Value = 'None';
            end
            delete(app)            
        end

        % Button pushed function: DataAlignmentButton
        function DataAlignmentButtonPushed(app, event)
            global axHandle_;
            global meshHierarchy_;
            global frameStruct4Voxelization_;

            app.DataAlignmentButton.Enable = 'off';
            pause(1);
            MGD_DataPreprocess();
            app.DataAlignmentButton.Enable = 'on';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'on';
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            hdSilhouette = Vis_DrawMesh3D(axHandle_, meshHierarchy_(1).boundaryNodeCoords, meshHierarchy_(1).boundaryEleFaces, 0);
            set(hdSilhouette, 'FaceColor', [252 141 98]/255, 'FaceAlpha', 0.15);
            hold(axHandle_, 'on');
            Vis_DrawGraph3D(axHandle_, frameStruct4Voxelization_.nodeCoords, frameStruct4Voxelization_.eNodMat, 500);
            view(axHandle_, az, el);
            Vis_UserLighting(axHandle_);            
        end

        % Button pushed function: VoxelizeMeshGraphEdgesButton
        function VoxelizeMeshGraphEdgesButtonPushed(app, event)
            global volumeFraction_;
            app.DataAlignmentButton.Enable = 'off';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'off';
            pause(1);
            volumeFraction_ = MGD_VoxelizeMeshEdges();
            app.DesignVolumeFractionEditField.Value = volumeFraction_;
            app.DataAlignmentButton.Enable = 'on';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'on';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'on';
            app.EdgeThicknessEditField.Enable = "on";
            app.LoadingAreaEditField_4.Enable = 'on';
            app.FixedAreaEditField_4.Enable = 'on';
            app.EntireBoundaryEditField.Enable = 'on';
            app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';
        end

        % Button pushed function: CreateVoxelbasedStructuralDesignButton
        function CreateVoxelbasedStructuralDesignButtonPushed(app, event)
            global volumeFraction_;
            global outPath_;
            app.DataAlignmentButton.Enable = 'off';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'off';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'off';
            app.EdgeThicknessEditField.Enable = "off";
            app.LoadingAreaEditField_4.Enable = 'off';
            app.FixedAreaEditField_4.Enable = 'off';
            app.EntireBoundaryEditField.Enable = 'off';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off';
            pause(1);
            edgeWidth = app.EdgeThicknessEditField.Value; edgeWidth = round(edgeWidth);
            numLayerboundary = app.EntireBoundaryEditField.Value; numLayerboundary = round(numLayerboundary);
            numLayerLoads = app.LoadingAreaEditField_4.Value; numLayerLoads = round(numLayerLoads);
            numLayerFixation = app.FixedAreaEditField_4.Value; numLayerFixation = round(numLayerFixation);
            volumeFraction_ = MGD_CoatMeshEdgesWithVoxels(edgeWidth, numLayerboundary, numLayerLoads, numLayerFixation);
            app.DesignVolumeFractionEditField.Value = volumeFraction_;
            app.DataAlignmentButton.Enable = 'on';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'on';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'on';
            app.EdgeThicknessEditField.Enable = "on";
            app.LoadingAreaEditField_4.Enable = 'on';
            app.FixedAreaEditField_4.Enable = 'on';
            app.EntireBoundaryEditField.Enable = 'on';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';
            pause(1);
	        fileName = strcat(outPath_, 'DesignVolume.nii');
	        IO_ExportDesignInVolume_nii(fileName);
            system('"./src/vape4d.exe" ./out/DesignVolume.nii');
        end

        % Button pushed function: 
        % StiffnessEvaluateofVoxelbasedStructuralDesignButton
        function StiffnessEvaluateofVoxelbasedStructuralDesignButtonPushed(app, event)
            global complianceDesign_;
            global volumeFraction_;
            global densityLayout_;
            global outPath_;
            app.DataAlignmentButton.Enable = 'off';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'off';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'off';
            app.EdgeThicknessEditField.Enable = "off";
            app.LoadingAreaEditField_4.Enable = 'off';
            app.FixedAreaEditField_4.Enable = 'off';
            app.EntireBoundaryEditField.Enable = 'off';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off';
            pause(1);    
            if isempty(densityLayout_)
                app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
                warning('No Voxel-based Design is Available!');
                return;                
            end
            [complianceDesign_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);
            %%
            app.DesignComplianceEditField.Value = complianceDesign_;
            app.DesignVolumeFractionEditField.Value = volumeFraction_;
            app.DataAlignmentButton.Enable = 'on';
            app.VoxelizeMeshGraphEdgesButton.Enable = 'on';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'on';
            app.EdgeThicknessEditField.Enable = "on";
            app.LoadingAreaEditField_4.Enable = 'on';
            app.FixedAreaEditField_4.Enable = 'on';
            app.EntireBoundaryEditField.Enable = 'on';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.MainApp.SimulationTasksDropDown.Enable = 'on';
            app.MainApp.EvaluationTasksDropDown.Enable = 'on';
            app.MainApp.ShowDeformationMenu.Enable = 'on';
            ShowDeformation_Public(app.MainApp);

            disp('Stress Analysis on Design ...');            
            tStressAnalysis = tic;
            dominantDirDesign = Common_ExtractDominantDirectionsFromPrincipalStressDirections();
            niftiwrite(dominantDirDesign, strcat(outPath_, 'dominantDirDesign.nii'));
            disp(['Done with Stress Analysis after ', sprintf('%.f', toc(tStressAnalysis)), 's']);
        end

        % Button pushed function: EvaluateStressAlignmentScaleButton
        function EvaluateStressAlignmentScaleButtonPushed(app, event)
            global outPath_;
            dominantDirSolid = niftiread(strcat(outPath_, 'dominantDirSolid.nii'));
            dominantDirDesign = niftiread(strcat(outPath_, 'dominantDirDesign.nii'));
            disp('Compute Stress Aligment Scale between Solid and Design...');
            tStressAligmentAna = tic;
            alignmentMetricVolume = Common_ComputeStressAlignmentDeviation(dominantDirSolid, dominantDirDesign);
            niftiwrite(alignmentMetricVolume, strcat(outPath_, 'alignmentMetricVolume.nii'));
            disp(['Done with Stress Alignment Analysis after ', sprintf('%.f', toc(tStressAligmentAna)), 's']);
            system('"./src/vape4d.exe" ./out/alignmentMetricVolume.nii');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MeshGraphbasedStructuralDesignPanel = uipanel(app.UIFigure);
            app.MeshGraphbasedStructuralDesignPanel.Title = 'Mesh/Graph-based Structural Design';
            app.ConvertPSLstoMaterialLayoutPanel = uipanel(app.MeshGraphbasedStructuralDesignPanel);
            app.ConvertPSLstoMaterialLayoutPanel.Title = 'Convert PSLs to Material Layout';
            app.EdgeThicknessEditFieldLabel = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.EdgeThicknessEditFieldLabel.Text = 'Edge Thickness';
            app.EdgeThicknessEditField = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.EdgeThicknessEditField.Value = 2;
            app.EntireBoundaryEditFieldLabel = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.EntireBoundaryEditFieldLabel.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.EntireBoundaryEditField.Value = 2;
            app.LoadingAreaEditField_4Label = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.LoadingAreaEditField_4Label.Text = 'Loading Area';
            app.LoadingAreaEditField_4 = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.FixedAreaEditField_4Label = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.FixedAreaEditField_4Label.Text = 'Fixed Area';
            app.FixedAreaEditField_4 = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.CreateVoxelbasedStructuralDesignButton = uibutton(app.ConvertPSLstoMaterialLayoutPanel, 'push');
            app.CreateVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @CreateVoxelbasedStructuralDesignButtonPushed, true);
            app.CreateVoxelbasedStructuralDesignButton.Text = 'Create Voxel based Structural Design';
            app.EvaluationPanel = uipanel(app.MeshGraphbasedStructuralDesignPanel);
            app.EvaluationPanel.Title = 'Evaluation';
            app.DesignComplianceEditFieldLabel = uilabel(app.EvaluationPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.EvaluationPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.EvaluationPanel, 'numeric');
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton = uibutton(app.EvaluationPanel, 'push');
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessEvaluateofVoxelbasedStructuralDesignButtonPushed, true);
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Text = 'Stiffness Evaluate of Voxel based Structural Design';
            app.EvaluateStressAlignmentScaleButton = uibutton(app.EvaluationPanel, 'push');
            app.EvaluateStressAlignmentScaleButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateStressAlignmentScaleButtonPushed, true);
            app.EvaluateStressAlignmentScaleButton.Text = 'Evaluate Stress Alignment Scale';
            app.EdgeVoxelizationPanel = uipanel(app.MeshGraphbasedStructuralDesignPanel);
            app.EdgeVoxelizationPanel.Title = 'Edge Voxelization';
            app.VoxelizeMeshGraphEdgesButton = uibutton(app.EdgeVoxelizationPanel, 'push');
            app.VoxelizeMeshGraphEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @VoxelizeMeshGraphEdgesButtonPushed, true);
            app.VoxelizeMeshGraphEdgesButton.Text = 'Voxelize Mesh/Graph Edges';
            app.PreprocessPanel = uipanel(app.MeshGraphbasedStructuralDesignPanel);
            app.PreprocessPanel.Title = 'Preprocess';
            app.DataAlignmentButton = uibutton(app.PreprocessPanel, 'push');
            app.DataAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @DataAlignmentButtonPushed, true);
            app.DataAlignmentButton.Text = 'Data Alignment';
            app.ExternalGraphCheckBox = uicheckbox(app.PreprocessPanel);
            app.ExternalGraphCheckBox.Text = 'External Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_MeshGraphBasedStructDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end