classdef SimTask_PSLsGuidedStructDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PSLsGenerationvia3DTSVPanel     matlab.ui.container.Panel
        DTSVButton                      matlab.ui.control.Button
        MinorCheckBox                   matlab.ui.control.CheckBox
        MediumCheckBox                  matlab.ui.control.CheckBox
        MajorCheckBox                   matlab.ui.control.CheckBox
        PSLsDensityControlEditField     matlab.ui.control.NumericEditField
        PSLsDensityControlEditFieldLabel  matlab.ui.control.Label
        PSLsguidedStructuralDesignPanel  matlab.ui.container.Panel
        StiffnessEvaluationPanel        matlab.ui.container.Panel
        StiffnessEvaluateofVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        ConvertPSLstoMaterialLayoutPanel  matlab.ui.container.Panel
        RunPSLsguidedStructuralDesignButton  matlab.ui.control.Button
        ConvertPSLsintoMateriallayoutButton  matlab.ui.control.Button
        FixedAreaEditField_4            matlab.ui.control.NumericEditField
        FixedAreaEditField_4Label       matlab.ui.control.Label
        LoadingAreaEditField_4          matlab.ui.control.NumericEditField
        LoadingAreaEditField_4Label     matlab.ui.control.Label
        EntireBoundaryEditField         matlab.ui.control.NumericEditField
        EntireBoundaryEditFieldLabel    matlab.ui.control.Label
        MaterialBudgetEditField         matlab.ui.control.NumericEditField
        MaterialBudgetEditFieldLabel    matlab.ui.control.Label
        MinimumThicknessEditField       matlab.ui.control.NumericEditField
        MinimumThicknessEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            GatherLSSandMPsettings(app.MainApp);
            app.DTSVButton.Enable = 'off';
            app.ConvertPSLsintoMateriallayoutButton.Enable = 'off';
            app.RunPSLsguidedStructuralDesignButton.Enable = 'off';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off';
            PSLs_Preparation4TSV();
            app.DTSVButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';
                app.MainApp.EvaluationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';
                app.MainApp.EvaluationTasksDropDown.Value = 'None';
            end            
            delete(app)              
        end

        % Button pushed function: DTSVButton
        function DTSVButtonPushed(app, event)
            global cartesianStressField_;
            if isempty(cartesianStressField_)
                warning('No Stress Field Existing!');
                return; 
            end
            app.DTSVButton.Enable = 'off'; pause(1);
            if ~(app.MajorCheckBox.Value || app.MediumCheckBox.Value || app.MinorCheckBox.Value)
                return;
            end
            psDirIndicator = zeros(1,3);
            if app.MajorCheckBox.Value, psDirIndicator(1) = 1; end
            if app.MediumCheckBox.Value, psDirIndicator(2) = 1; end
            if app.MinorCheckBox.Value, psDirIndicator(3) = 1; end
            lineDensCtrl = app.PSLsDensityControlEditField.Value;
            if lineDensCtrl <=0, return; end
            app.DTSVButton.Enable = 'off';
            pause(1);
            PSLs_GeneratePSLsBy3DTSV(lineDensCtrl, psDirIndicator);
            ShowPSLs_Public(app.MainApp)
            app.DTSVButton.Enable = 'on';
            app.ConvertPSLsintoMateriallayoutButton.Enable = 'on';
            app.MainApp.SimulationTasksDropDown.Enable = 'on';
            app.MainApp.PSLsMenu.Enable = 'on';
        end

        % Button pushed function: ConvertPSLsintoMateriallayoutButton
        function ConvertPSLsintoMateriallayoutButtonPushed(app, event)
            numLayerPSLs = round(app.MinimumThicknessEditField.Value);
            numLayerboundary = round(app.EntireBoundaryEditField.Value);
            numLayerLoads = round(app.LoadingAreaEditField_4.Value);
            numLayerFixation = round(app.FixedAreaEditField_4.Value);
            if 0==numLayerPSLs, return; end
            app.DTSVButton.Enable = 'off';
            app.ConvertPSLsintoMateriallayoutButton.Enable = 'off';
            app.RunPSLsguidedStructuralDesignButton.Enable = 'off';
            pause(1);
            volumeFractionOfVoxelizedMeshEdges = PSLs_VoxelizePSLs(numLayerPSLs, numLayerboundary, numLayerLoads, numLayerFixation);
            app.DesignVolumeFractionEditField.Value = volumeFractionOfVoxelizedMeshEdges;
            app.ConvertPSLsintoMateriallayoutButton.Enable = 'on';
            app.DTSVButton.Enable = 'on';
            app.RunPSLsguidedStructuralDesignButton.Enable = 'on';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';
        end

        % Button pushed function: RunPSLsguidedStructuralDesignButton
        function RunPSLsguidedStructuralDesignButtonPushed(app, event)
            targetDepositionRatio = app.MaterialBudgetEditField.Value;
            numLayerPSLs = round(app.MinimumThicknessEditField.Value);
            if targetDepositionRatio<=0, return; end
            if numLayerPSLs<2, return; end
            app.DTSVButton.Enable = 'off';
            app.ConvertPSLsintoMateriallayoutButton.Enable = 'off';
            app.RunPSLsguidedStructuralDesignButton.Enable = 'off';
            pause(1);
            volumeFractionOfVoxelizedMeshEdges = PSLs_RunPSLsGuidedStructDesign(targetDepositionRatio, numLayerPSLs);
            app.DesignVolumeFractionEditField.Value = volumeFractionOfVoxelizedMeshEdges;
            app.DTSVButton.Enable = 'on';
            app.ConvertPSLsintoMateriallayoutButton.Enable = 'on';
            app.RunPSLsguidedStructuralDesignButton.Enable = 'on';            
        end

        % Button pushed function: 
        % StiffnessEvaluateofVoxelbasedStructuralDesignButton
        function StiffnessEvaluateofVoxelbasedStructuralDesignButtonPushed(app, event)
            global compliance_;
            global volumeFraction_;
            global densityLayout_;
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off'; pause(1);
            [compliance_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);
            app.DesignComplianceEditField.Value = compliance_;
            app.DesignVolumeFractionEditField.Value = volumeFraction_;
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PSLsguidedStructuralDesignPanel = uipanel(app.UIFigure);
            app.PSLsguidedStructuralDesignPanel.Title = 'PSLs-guided Structural Design';
            app.ConvertPSLstoMaterialLayoutPanel = uipanel(app.PSLsguidedStructuralDesignPanel);
            app.ConvertPSLstoMaterialLayoutPanel.Title = 'Convert PSLs to Material Layout';
            app.MinimumThicknessEditFieldLabel = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.MinimumThicknessEditFieldLabel.Text = 'Minimum Thickness';
            app.MinimumThicknessEditField = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.MinimumThicknessEditField.Value = 2;
            app.MaterialBudgetEditFieldLabel = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.MaterialBudgetEditFieldLabel.Text = 'Material Budget';
            app.MaterialBudgetEditField = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.MaterialBudgetEditField.Value = 0.5;
            app.EntireBoundaryEditFieldLabel = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.EntireBoundaryEditFieldLabel.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.EntireBoundaryEditField.Value = 2;
            app.LoadingAreaEditField_4Label = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.LoadingAreaEditField_4Label.Text = 'Loading Area';
            app.LoadingAreaEditField_4 = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.LoadingAreaEditField_4.Value = 2;
            app.FixedAreaEditField_4Label = uilabel(app.ConvertPSLstoMaterialLayoutPanel);
            app.FixedAreaEditField_4Label.Text = 'Fixed Area';
            app.FixedAreaEditField_4 = uieditfield(app.ConvertPSLstoMaterialLayoutPanel, 'numeric');
            app.FixedAreaEditField_4.Value = 2;
            app.ConvertPSLsintoMateriallayoutButton = uibutton(app.ConvertPSLstoMaterialLayoutPanel, 'push');
            app.ConvertPSLsintoMateriallayoutButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertPSLsintoMateriallayoutButtonPushed, true);
            app.ConvertPSLsintoMateriallayoutButton.Text = 'Convert PSLs into Material layout';
            app.RunPSLsguidedStructuralDesignButton = uibutton(app.ConvertPSLstoMaterialLayoutPanel, 'push');
            app.RunPSLsguidedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @RunPSLsguidedStructuralDesignButtonPushed, true);
            app.RunPSLsguidedStructuralDesignButton.Text = 'Run PSLs-guided Structural Design';
            app.StiffnessEvaluationPanel = uipanel(app.PSLsguidedStructuralDesignPanel);
            app.StiffnessEvaluationPanel.Title = 'Stiffness Evaluation';
            app.DesignComplianceEditFieldLabel = uilabel(app.StiffnessEvaluationPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.StiffnessEvaluationPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.StiffnessEvaluationPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.StiffnessEvaluationPanel, 'numeric');
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton = uibutton(app.StiffnessEvaluationPanel, 'push');
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessEvaluateofVoxelbasedStructuralDesignButtonPushed, true);
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Text = 'Stiffness Evaluate of Voxel based Structural Design';
            app.PSLsGenerationvia3DTSVPanel = uipanel(app.UIFigure);
            app.PSLsGenerationvia3DTSVPanel.Title = 'PSLs Generation via 3D-TSV';
            app.PSLsDensityControlEditFieldLabel = uilabel(app.PSLsGenerationvia3DTSVPanel);
            app.PSLsDensityControlEditFieldLabel.Text = 'PSLs Density Control';
            app.PSLsDensityControlEditField = uieditfield(app.PSLsGenerationvia3DTSVPanel, 'numeric');
            app.PSLsDensityControlEditField.Value = 10;
            app.MajorCheckBox = uicheckbox(app.PSLsGenerationvia3DTSVPanel);
            app.MajorCheckBox.Text = 'Major';
            app.MajorCheckBox.Value = true;
            app.MediumCheckBox = uicheckbox(app.PSLsGenerationvia3DTSVPanel);
            app.MediumCheckBox.Text = 'Medium';
            app.MinorCheckBox = uicheckbox(app.PSLsGenerationvia3DTSVPanel);
            app.MinorCheckBox.Text = 'Minor';
            app.MinorCheckBox.Value = true;
            app.DTSVButton = uibutton(app.PSLsGenerationvia3DTSVPanel, 'push');
            app.DTSVButton.ButtonPushedFcn = createCallbackFcn(app, @DTSVButtonPushed, true);
            app.DTSVButton.Text = '3D-TSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_PSLsGuidedStructDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end